classdef NewProjectConfigurator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfiguratorUIFigure      matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout5               matlab.ui.container.GridLayout
        Button_Cancel             matlab.ui.control.Button
        Button_Create             matlab.ui.control.Button
        Button_ProjectSetting     matlab.ui.control.Button
        CheckBox_IgnoreImport     matlab.ui.control.CheckBox
        GridLayout4               matlab.ui.container.GridLayout
        DropDown_ProjectTemplate  matlab.ui.control.DropDown
        Label_3                   matlab.ui.control.Label
        GridLayout3               matlab.ui.container.GridLayout
        DropDown_ProjectLocation  matlab.ui.control.DropDown
        Label_2                   matlab.ui.control.Label
        GridLayout2               matlab.ui.container.GridLayout
        EditField_ProjectName     matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
    end

    properties (Constant)
        VER = "1.0.0"
    end

    properties (Access = private)
        caller      % 1-by-1 Register object
        config      % 1-by-1 struct with project configuration
        langmap     % 1-by-1 struct with language mapping
    end
    
    methods (Access = ?Register)
        
        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr > 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    
                case "DARK"
                    
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            % get language map
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            % apply languages
            app.Label.Text = app.langmap.PROJECT_NAME;
            app.Label_2.Text = app.langmap.POSITION;
            app.Label_3.Text = app.langmap.TEMPLATE;
            app.CheckBox_IgnoreImport.Text = app.langmap.IGNORE_IMPORT_STEP;
            app.Button_Create.Text = app.langmap.CREATION;
            app.Button_Cancel.Text = app.langmap.CANCEL;
        end

        function RefreshTooltips(app)
            
        end

        function Close(app)
            % ~
            delete(app)
        end
    end

    methods (Access = private)
        function InitData(app)

        end

        function InitAppearance(app)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.caller = caller;

            InitData(app);

            InitAppearance(app);
        end

        % Value changed function: EditField_ProjectName
        function EditField_ProjectNameValueChanged(app, event)
            value = app.EditField_ProjectName.Value;
            
        end

        % Value changed function: DropDown_ProjectLocation
        function DropDown_ProjectLocationValueChanged(app, event)
            value = app.DropDown_ProjectLocation.Value;
            
        end

        % Value changed function: DropDown_ProjectTemplate
        function DropDown_ProjectTemplateValueChanged(app, event)
            value = app.DropDown_ProjectTemplate.Value;
            
        end

        % Value changed function: CheckBox_IgnoreImport
        function CheckBox_IgnoreImportValueChanged(app, event)
            value = app.CheckBox_IgnoreImport.Value;
            
        end

        % Button pushed function: Button_ProjectSetting
        function Button_ProjectSettingPushed(app, event)
            
        end

        % Button pushed function: Button_Create
        function Button_CreatePushed(app, event)
            
        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfiguratorUIFigure = uifigure('Visible', 'off');
            app.ConfiguratorUIFigure.Name = 'Configurator';
            app.ConfiguratorUIFigure.Icon = 'new_project.png';
            app.GridLayout = uigridlayout(app.ConfiguratorUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '项目名';
            app.EditField_ProjectName = uieditfield(app.GridLayout2, 'text');
            app.EditField_ProjectName.CharacterLimits = [0 255];
            app.EditField_ProjectName.ValueChangedFcn = createCallbackFcn(app, @EditField_ProjectNameValueChanged, true);
            app.EditField_ProjectName.Value = '未命名';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '位置';
            app.DropDown_ProjectLocation = uidropdown(app.GridLayout3);
            app.DropDown_ProjectLocation.Items = {'/home/Reg3D Projects', '选择位置'};
            app.DropDown_ProjectLocation.ItemsData = {'1', '0'};
            app.DropDown_ProjectLocation.ValueChangedFcn = createCallbackFcn(app, @DropDown_ProjectLocationValueChanged, true);
            app.DropDown_ProjectLocation.Value = '1';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = '模板';
            app.DropDown_ProjectTemplate = uidropdown(app.GridLayout4);
            app.DropDown_ProjectTemplate.Items = {'无', '标准配准流程', '媒体工作流', '开发者工作流', '选择模板'};
            app.DropDown_ProjectTemplate.ItemsData = {'NONE', 'STDREG', 'MEDIA', 'DEV', 'SLT'};
            app.DropDown_ProjectTemplate.ValueChangedFcn = createCallbackFcn(app, @DropDown_ProjectTemplateValueChanged, true);
            app.DropDown_ProjectTemplate.Value = 'NONE';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.CheckBox_IgnoreImport = uicheckbox(app.GridLayout5);
            app.CheckBox_IgnoreImport.ValueChangedFcn = createCallbackFcn(app, @CheckBox_IgnoreImportValueChanged, true);
            app.CheckBox_IgnoreImport.Text = '跳过导入模式';
            app.Button_ProjectSetting = uibutton(app.GridLayout5, 'push');
            app.Button_ProjectSetting.ButtonPushedFcn = createCallbackFcn(app, @Button_ProjectSettingPushed, true);
            app.Button_ProjectSetting.Icon = 'setting_single.png';
            app.Button_ProjectSetting.Enable = 'off';
            app.Button_ProjectSetting.Text = '';
            app.Button_Create = uibutton(app.GridLayout5, 'push');
            app.Button_Create.ButtonPushedFcn = createCallbackFcn(app, @Button_CreatePushed, true);
            app.Button_Create.Text = '创建';
            app.Button_Cancel = uibutton(app.GridLayout5, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Text = '取消';
            % Show the figure after all components are created
            app.ConfiguratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewProjectConfigurator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConfiguratorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConfiguratorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfiguratorUIFigure)
        end
    end
end